classdef start_program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EnterCustomToolParametersButton_2  matlab.ui.control.Button
        RunFEAThermalAnalysisButton  matlab.ui.control.Button
        DisplayResultsButton_2       matlab.ui.control.Button
        RunFEALoadAnalysisButton     matlab.ui.control.Button
        UseDefaultToolButton         matlab.ui.control.Button
        StatusWaitingforusertoclickanavailablebuttonLabel  matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function start(app, event)

        end

        % Button pushed function: EnterCustomToolParametersButton_2
        function start_py_gui(app, event)
            % Update user
            app.StatusWaitingforusertoclickanavailablebuttonLabel.Text = 'Status: Setting up custom tool. Please wait for it to open automatically...';
            m_box = msgbox(["Please wait while the program launches the custom tool window"; "This may take a minute"; "Please refrain from closing the app during this time"], "Loading...");
            drawnow;
            pause(1);
            % Run python gui to create custom mesh
            project_folder = fileparts(mfilename('fullpath'));
            system(fullfile(project_folder, 'dist/mesh_generation.exe'));
            delete(m_box);
            app.RunFEALoadAnalysisButton.Enable = "on";
            app.RunFEAThermalAnalysisButton.Enable = "on";
            app.StatusWaitingforusertoclickanavailablebuttonLabel.Text = 'Status: Ready to perform analysis.';
        end

        % Button pushed function: UseDefaultToolButton
        function default_tool(app, event)
            % Update user
            app.StatusWaitingforusertoclickanavailablebuttonLabel.Text = 'Status: Setting default geometry, parameters, and data...';
            m_box = msgbox(["Please wait while the geometry, parameters, and data are initialised"; "This may take a minute"; "Please refrain from closing the app during this time"], "Loading...");
            drawnow;
            pause(1);
            % Run program for default tool
            run('generate_cutting_tool_data.m')
            % Display tool mesh
            delete(m_box);
            figure;
            image(imread('default_cutting_tool_preview.png'))
            app.RunFEALoadAnalysisButton.Enable = "on";
            app.RunFEAThermalAnalysisButton.Enable = "on";
            app.StatusWaitingforusertoclickanavailablebuttonLabel.Text = 'Status: Ready to perform analysis.';
        end

        % Button pushed function: RunFEALoadAnalysisButton
        function run_main_analysis(app, event)
            % Update user
            app.StatusWaitingforusertoclickanavailablebuttonLabel.Text = 'Status: Running FEA Load Analysis...';
            m_box = msgbox(["Please wait while the displacements, stresses, and strains are calculated"; "This may take a minute"; "Please refrain from closing the app during this time"], "Loading...");
            drawnow;
            pause(1);
            % Run code developed in class
            run('Main.m')
            delete(m_box);
            app.DisplayResultsButton_2.Enable = "on";
            app.StatusWaitingforusertoclickanavailablebuttonLabel.Text = 'Status: Load analysis complete, ready to display results.';
        end

        % Button pushed function: DisplayResultsButton_2
        function display_results(app, event)
            % Update user
            app.StatusWaitingforusertoclickanavailablebuttonLabel.Text = 'Status: Loading display in "data visualisation" window...';
            m_box = msgbox(["Please wait while the external display window loads"; "This may take a minute"; "Please refrain from closing the app during this time"], "Loading...");
            drawnow;
            pause(1);
            % Run py_gui_results with coloured mesh that shows sigma x, sigma y, and gammy xy for each element
            project_folder = fileparts(mfilename('fullpath'));
            system(fullfile(project_folder, 'dist/visualisation.exe'));
            delete(m_box);
            app.RunFEALoadAnalysisButton.Enable = "off";
            app.RunFEAThermalAnalysisButton.Enable = "off";
            app.DisplayResultsButton_2.Enable = "off";
            app.StatusWaitingforusertoclickanavailablebuttonLabel.Text = 'Status: Waiting for user to click an available button...';
        end

        % Button pushed function: RunFEAThermalAnalysisButton
        function RunFEAThermalAnalysisButtonPushed(app, event)
            % Update user
            app.StatusWaitingforusertoclickanavailablebuttonLabel.Text = 'Running FEA Thermal Analysis...';
            m_box = msgbox(["Please wait while the thermal forces and temperatures are calculated"; "This may take a minute"; "Please refrain from closing the app during this time"], "Loading...");
            drawnow;
            pause(1);
            % Run thermal program
            run('ThermalSolver1.m')
            delete(m_box);
            app.DisplayResultsButton_2.Enable = "on";
            app.StatusWaitingforusertoclickanavailablebuttonLabel.Text = 'Status: Thermal analysis complete, ready to display results.';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StatusWaitingforusertoclickanavailablebuttonLabel = uilabel(app.UIFigure);
            app.StatusWaitingforusertoclickanavailablebuttonLabel.Tag = 'Status';
            app.StatusWaitingforusertoclickanavailablebuttonLabel.Text = 'Status: Waiting for user to click an available button...';
            app.UseDefaultToolButton = uibutton(app.UIFigure, 'push');
            app.UseDefaultToolButton.ButtonPushedFcn = createCallbackFcn(app, @default_tool, true);
            app.UseDefaultToolButton.Tag = 'Start-button';
            app.UseDefaultToolButton.Text = 'Use Default Tool';
            app.RunFEALoadAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunFEALoadAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @run_main_analysis, true);
            app.RunFEALoadAnalysisButton.Tag = 'Start-button';
            app.RunFEALoadAnalysisButton.Enable = 'off';
            app.RunFEALoadAnalysisButton.Text = 'Run FEA Load Analysis';
            app.DisplayResultsButton_2 = uibutton(app.UIFigure, 'push');
            app.DisplayResultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @display_results, true);
            app.DisplayResultsButton_2.Tag = 'Start-button';
            app.DisplayResultsButton_2.Enable = 'off';
            app.DisplayResultsButton_2.Text = 'Display Results';
            app.RunFEAThermalAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunFEAThermalAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunFEAThermalAnalysisButtonPushed, true);
            app.RunFEAThermalAnalysisButton.Tag = 'Start-button';
            app.RunFEAThermalAnalysisButton.Enable = 'off';
            app.RunFEAThermalAnalysisButton.Text = 'Run FEA Thermal Analysis';
            app.EnterCustomToolParametersButton_2 = uibutton(app.UIFigure, 'push');
            app.EnterCustomToolParametersButton_2.ButtonPushedFcn = createCallbackFcn(app, @start_py_gui, true);
            app.EnterCustomToolParametersButton_2.Tag = 'Start-button';
            app.EnterCustomToolParametersButton_2.Text = {'Enter Custom Tool '; 'Parameters'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = start_program

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end